classdef CelegansLocomotion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RndSeedEditField                matlab.ui.control.NumericEditField
        RndSeedEditFieldLabel           matlab.ui.control.Label
        SynchMeasureDropDown            matlab.ui.control.DropDown
        SynchMeasureDropDownLabel       matlab.ui.control.Label
        NoiseAmppAEditField             matlab.ui.control.NumericEditField
        NoiseAmppAEditFieldLabel        matlab.ui.control.Label
        stdsynvarEditField              matlab.ui.control.NumericEditField
        stdsynvarEditFieldLabel         matlab.ui.control.Label
        meansynvarEditField             matlab.ui.control.NumericEditField
        meansynvarEditFieldLabel        matlab.ui.control.Label
        RuntimesecEditField             matlab.ui.control.NumericEditField
        RuntimesecEditFieldLabel        matlab.ui.control.Label
        GapButton                       matlab.ui.control.StateButton
        NormalizeindegreesLabel         matlab.ui.control.Label
        stdedgeweightsEditField         matlab.ui.control.NumericEditField
        stdedgeweightsEditFieldLabel    matlab.ui.control.Label
        ChemButton                      matlab.ui.control.StateButton
        NoisyConnectomeButton           matlab.ui.control.StateButton
        stdinitialVoltmVEditField       matlab.ui.control.NumericEditField
        stdinitialVoltmVEditFieldLabel  matlab.ui.control.Label
        meaninitialVoltmVEditField      matlab.ui.control.NumericEditField
        meaninitialVoltmVEditFieldLabel  matlab.ui.control.Label
        UniversalthresholdButton        matlab.ui.control.StateButton
        DrivingCurrentpAEditField       matlab.ui.control.NumericEditField
        DrivingCurrentpALabel           matlab.ui.control.Label
        RandomInitalStateButton         matlab.ui.control.StateButton
        SynapticVariableButton          matlab.ui.control.StateButton
        Image                           matlab.ui.control.Image
        ChemStrengthEditField           matlab.ui.control.NumericEditField
        ByBryantEAvilaLabel             matlab.ui.control.Label
        OscillCurrrentpAEditField       matlab.ui.control.NumericEditField
        DrivingvoltageLabel             matlab.ui.control.Label
        RIBLEditField                   matlab.ui.control.NumericEditField
        RIBLEditFieldLabel              matlab.ui.control.Label
        RIBREditField                   matlab.ui.control.NumericEditField
        RIBREditFieldLabel              matlab.ui.control.Label
        PVCLEditField                   matlab.ui.control.NumericEditField
        PVCLEditFieldLabel              matlab.ui.control.Label
        PVCREditField                   matlab.ui.control.NumericEditField
        PVCREditFieldLabel              matlab.ui.control.Label
        AVBLEditField                   matlab.ui.control.NumericEditField
        AVBLEditFieldLabel              matlab.ui.control.Label
        AVBREditField                   matlab.ui.control.NumericEditField
        AVBREditFieldLabel              matlab.ui.control.Label
        VB11EditField                   matlab.ui.control.NumericEditField
        VB11EditFieldLabel              matlab.ui.control.Label
        VB10EditField                   matlab.ui.control.NumericEditField
        VB10EditFieldLabel              matlab.ui.control.Label
        VB09EditField                   matlab.ui.control.NumericEditField
        VB09EditFieldLabel              matlab.ui.control.Label
        VB08EditField                   matlab.ui.control.NumericEditField
        VB08EditFieldLabel              matlab.ui.control.Label
        VB07EditField                   matlab.ui.control.NumericEditField
        VB07EditFieldLabel              matlab.ui.control.Label
        VB06EditField                   matlab.ui.control.NumericEditField
        VB06EditFieldLabel              matlab.ui.control.Label
        VB05EditField                   matlab.ui.control.NumericEditField
        VB05EditFieldLabel              matlab.ui.control.Label
        VB04EditField                   matlab.ui.control.NumericEditField
        VB04EditFieldLabel              matlab.ui.control.Label
        VB03EditField                   matlab.ui.control.NumericEditField
        VB03EditFieldLabel              matlab.ui.control.Label
        VB02EditField                   matlab.ui.control.NumericEditField
        VB02EditFieldLabel              matlab.ui.control.Label
        VB01EditField                   matlab.ui.control.NumericEditField
        VB01EditFieldLabel              matlab.ui.control.Label
        DB07EditField                   matlab.ui.control.NumericEditField
        DB07EditFieldLabel              matlab.ui.control.Label
        DB06EditField                   matlab.ui.control.NumericEditField
        DB06EditFieldLabel              matlab.ui.control.Label
        DB05EditField                   matlab.ui.control.NumericEditField
        DB05EditFieldLabel              matlab.ui.control.Label
        DB04EditField                   matlab.ui.control.NumericEditField
        DB04EditFieldLabel              matlab.ui.control.Label
        DB03EditField                   matlab.ui.control.NumericEditField
        DB03EditFieldLabel              matlab.ui.control.Label
        DB02EditField                   matlab.ui.control.NumericEditField
        DB02EditFieldLabel              matlab.ui.control.Label
        DB01EditField                   matlab.ui.control.NumericEditField
        DB01EditFieldLabel              matlab.ui.control.Label
        ForwardNeuronsDrivingFrequencyHzLabel  matlab.ui.control.Label
        AVALEditField                   matlab.ui.control.NumericEditField
        AVALEditFieldLabel              matlab.ui.control.Label
        AVAREditField                   matlab.ui.control.NumericEditField
        AVAREditFieldLabel              matlab.ui.control.Label
        AIBLEditField                   matlab.ui.control.NumericEditField
        AIBLEditFieldLabel              matlab.ui.control.Label
        AIBREditField                   matlab.ui.control.NumericEditField
        AIBREditFieldLabel              matlab.ui.control.Label
        RIMLEditField                   matlab.ui.control.NumericEditField
        RIMLEditFieldLabel              matlab.ui.control.Label
        RIMREditField                   matlab.ui.control.NumericEditField
        RIMREditFieldLabel              matlab.ui.control.Label
        AVDLEditField                   matlab.ui.control.NumericEditField
        AVDLEditFieldLabel              matlab.ui.control.Label
        AVDREditField                   matlab.ui.control.NumericEditField
        AVDREditFieldLabel              matlab.ui.control.Label
        AVELEditField                   matlab.ui.control.NumericEditField
        AVELEditFieldLabel              matlab.ui.control.Label
        AVEREditField                   matlab.ui.control.NumericEditField
        AVEREditFieldLabel              matlab.ui.control.Label
        VA12EditField                   matlab.ui.control.NumericEditField
        VA12EditFieldLabel              matlab.ui.control.Label
        VA11EditField                   matlab.ui.control.NumericEditField
        VA11EditFieldLabel              matlab.ui.control.Label
        VA10EditField                   matlab.ui.control.NumericEditField
        VA10EditFieldLabel              matlab.ui.control.Label
        VA09EditField                   matlab.ui.control.NumericEditField
        VA09EditFieldLabel              matlab.ui.control.Label
        VA08EditField                   matlab.ui.control.NumericEditField
        VA08EditFieldLabel              matlab.ui.control.Label
        VA07EditField                   matlab.ui.control.NumericEditField
        VA07EditFieldLabel              matlab.ui.control.Label
        VA06EditField                   matlab.ui.control.NumericEditField
        VA06EditFieldLabel              matlab.ui.control.Label
        VA05EditField                   matlab.ui.control.NumericEditField
        VA05EditFieldLabel              matlab.ui.control.Label
        VA04EditField                   matlab.ui.control.NumericEditField
        VA04EditFieldLabel              matlab.ui.control.Label
        VA03EditField                   matlab.ui.control.NumericEditField
        VA03EditFieldLabel              matlab.ui.control.Label
        VA02EditField                   matlab.ui.control.NumericEditField
        VA02EditFieldLabel              matlab.ui.control.Label
        VA01EditField                   matlab.ui.control.NumericEditField
        VA01EditFieldLabel              matlab.ui.control.Label
        DA09EditField                   matlab.ui.control.NumericEditField
        DA09EditFieldLabel              matlab.ui.control.Label
        DA08EditField                   matlab.ui.control.NumericEditField
        DA08EditFieldLabel              matlab.ui.control.Label
        DA07EditField                   matlab.ui.control.NumericEditField
        DA07EditFieldLabel              matlab.ui.control.Label
        DA06EditField                   matlab.ui.control.NumericEditField
        DA06EditFieldLabel              matlab.ui.control.Label
        DA05EditField                   matlab.ui.control.NumericEditField
        DA05EditFieldLabel              matlab.ui.control.Label
        DA04EditField                   matlab.ui.control.NumericEditField
        DA04EditFieldLabel              matlab.ui.control.Label
        DA03EditField                   matlab.ui.control.NumericEditField
        DA03EditFieldLabel              matlab.ui.control.Label
        DA02EditField                   matlab.ui.control.NumericEditField
        DA02EditFieldLabel              matlab.ui.control.Label
        DA01EditField                   matlab.ui.control.NumericEditField
        DA01EditFieldLabel              matlab.ui.control.Label
        BackwardNeuronsDrivingFrequencyHzLabel  matlab.ui.control.Label
        ChemStrengthEditFieldLabel      matlab.ui.control.Label
        GapStrengthEditField            matlab.ui.control.NumericEditField
        GapStrengthEditFieldLabel       matlab.ui.control.Label
        IntraconnectivityDropDown       matlab.ui.control.DropDown
        IntraconnectivityDropDownLabel  matlab.ui.control.Label
        InterconnectivityDropDown       matlab.ui.control.DropDown
        InterconnectivityDropDownLabel  matlab.ui.control.Label
        WeightsSwitch                   matlab.ui.control.Switch
        WeightsSwitchLabel              matlab.ui.control.Label
        IntraconnectedButton            matlab.ui.control.StateButton
        RunButton                       matlab.ui.control.Button
        ViewSwitch                      matlab.ui.control.Switch
        ViewSwitchLabel                 matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        View = "Chem" % Type of view. Gap or Chem
        Random_Removal = 0 % If edges are randomly removed or not, set to zero.
        Binary_System = 1 % Are edges binary or weighted
        Intra_Connections = 0% Are intra-connections present or not
        Chem_strength = 1% Activation level of chem interactions
        Gap_strength = 0% Activation level of gap interactions
        inter_symmetry = "Hernan Symmetric Weighted";
        intra_symmetry = "LR Symmetric Weighted";
        Data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            voltage_array = [app.DA01EditField.Value, app.DA02EditField.Value, app.DA03EditField.Value, app.DA04EditField.Value, app.DA05EditField.Value, app.DA06EditField.Value, app.DA07EditField.Value, app.DA08EditField.Value, app.DA09EditField.Value, ...
                            app.DB01EditField.Value, app.DB02EditField.Value, app.DB03EditField.Value, app.DB04EditField.Value, app.DB05EditField.Value, app.DB06EditField.Value, app.DB07EditField.Value, ...
                            app.VA01EditField.Value, app.VA02EditField.Value, app.VA03EditField.Value, app.VA04EditField.Value, app.VA05EditField.Value, app.VA06EditField.Value, app.VA07EditField.Value, app.VA08EditField.Value, app.VA09EditField.Value, app.VA10EditField.Value, app.VA11EditField.Value, app.VA12EditField.Value, ...
                            app.VB01EditField.Value, app.VB02EditField.Value, app.VB03EditField.Value, app.VB04EditField.Value, app.VB05EditField.Value, app.VB06EditField.Value, app.VB07EditField.Value, app.VB08EditField.Value, app.VB09EditField.Value, app.VB10EditField.Value, app.VB11EditField.Value, ...
                            app.AVAREditField.Value, app.AVALEditField.Value, app.AVDREditField.Value, app.AVDLEditField.Value, app.RIMREditField.Value, app.RIMLEditField.Value, app.AIBREditField.Value, app.AIBLEditField.Value, app.AVEREditField.Value, app.AVELEditField.Value, ...
                            app.AVBREditField.Value, app.AVBLEditField.Value, app.PVCREditField.Value, app.PVCLEditField.Value, app.RIBREditField.Value, app.RIBLEditField.Value];
            app.RunButton.BackgroundColor = [0.00,1.00,0.00]; app.RunButton.FontColor = [0.15,0.15,0.15]; app.UIFigure.Color = [0.65,0.65,0.65]; app.RunButton.Text = 'Running...';
            pause(0.1)
            try
                app.Data = C_elegans_dynamics(app.NoisyConnectomeButton.Value,app.stdedgeweightsEditField.Value,app.ChemButton.Value,app.GapButton.Value,app.View,app.inter_symmetry,app.intra_symmetry,app.Intra_Connections,app.Binary_System, ...
                    app.Random_Removal,app.NoiseAmppAEditField.Value,app.ChemStrengthEditField.Value,app.GapStrengthEditField.Value,app.OscillCurrrentpAEditField.Value,app.DrivingCurrentpAEditField.Value,voltage_array,app.SynapticVariableButton.Value, ...
                    app.RandomInitalStateButton.Value,app.UniversalthresholdButton.Value,app.meaninitialVoltmVEditField.Value,app.stdinitialVoltmVEditField.Value,app.RuntimesecEditField.Value,app.stdsynvarEditField.Value,app.meansynvarEditField.Value, ...
                    app.SynchMeasureDropDown.Value,app.RndSeedEditField.Value);
                clf(figure(1));
                h1 = figure(1); g1 = openfig("backward_synchs.fig","visible"); copyobj(g1.get.Children,h1); delete(g1);          
                clf(figure(2));
                h2 = figure(2); g2 = openfig("forward_synchs.fig","visible"); copyobj(g2.get.Children,h2); delete(g2);
                clf(figure(3));
                h3 = figure(3); g3 = openfig("backward_dynamics.fig","visible"); copyobj(g3.get.Children,h3); delete(g3);            
                clf(figure(4));
                h4 = figure(4); g4 = openfig("forward_dynamics.fig","visible"); copyobj(g4.get.Children,h4); delete(g4);
%                  clf(figure(5));
%                  h5 = figure(5); g5 = openfig("heat.fig","visible"); copyobj(g5.get.Children,h5); delete(g5);
                app.RunButton.BackgroundColor = [0.15,0.15,0.15]; app.RunButton.FontColor = [0.94,0.94,0.94]; app.UIFigure.Color = [0.94,0.94,0.94]; app.RunButton.Text = 'Run';
            catch
                app.RunButton.BackgroundColor = [0.9,0.7,0.15]; app.RunButton.FontColor = [0.05,0.05,0.05]; app.UIFigure.Color = [0.95,0.15,0.15]; app.RunButton.Text = 'Error';
            end

            clear
        end

        % Value changed function: ViewSwitch
        function ViewSwitchValueChanged(app, event)
            app.View = app.ViewSwitch.Value;
            if app.ViewSwitch.Value == "Chem"
                app.ChemStrengthEditField.Value = 1; app.GapStrengthEditField.Value = 0;
                app.AIBREditField.Value = 0; app.AIBLEditField.Value = 0; app.PVCREditField.Value = 2; app.PVCLEditField.Value = 2; app.RIBLEditField.Value = 0; app.RIBREditField.Value = 0;
                app.DA05EditField.Value = 0; app.AVAREditField.Value = 2; app.AVALEditField.Value = 2; app.DB04EditField.Value = 0; app.AVBREditField.Value = 0; app.AVBLEditField.Value = 0;
            elseif app.ViewSwitch.Value == "Gap"
                app.ChemStrengthEditField.Value = 0; app.GapStrengthEditField.Value = 1;
                app.DA05EditField.Value = -1; app.AVAREditField.Value = 0; app.AVALEditField.Value = 0; app.DB04EditField.Value = -1; app.AVBREditField.Value = 2; app.AVBLEditField.Value = 2;
                app.AIBREditField.Value = 2; app.AIBLEditField.Value = 2; app.PVCREditField.Value = 0; app.PVCLEditField.Value = 0; app.RIBLEditField.Value = 0; app.RIBREditField.Value = 0;
            end
        end

        % Value changed function: WeightsSwitch
        function WeightsSwitchValueChanged(app, event)
            if app.WeightsSwitch.Value == "Binary"
                app.Binary_System = 1;
            elseif app.WeightsSwitch.Value == "Integer"
                app.Binary_System = 0;
            end
        end

        % Callback function
        function ChemStrengthSliderValueChanged(app, event)

        end

        % Callback function
        function GapStrengthSliderValueChanged(app, event)

        end

        % Value changed function: IntraconnectedButton
        function IntraconnectedButtonValueChanged(app, event)
            app.Intra_Connections = app.IntraconnectedButton.Value;
            if app.Intra_Connections == 0
                app.IntraconnectivityDropDown.Enable = 'off';
                app.IntraconnectivityDropDownLabel.Enable = 'off';
            elseif app.Intra_Connections == 1
                app.IntraconnectivityDropDown.Enable = 'on';
                app.IntraconnectivityDropDownLabel.Enable = 'on';
            end
        end

        % Value changed function: ChemStrengthEditField
        function ChemStrengthEditFieldValueChanged(app, event)
            app.Chem_strength = app.ChemStrengthEditField.Value;
            
        end

        % Value changed function: GapStrengthEditField
        function GapStrengthEditFieldValueChanged(app, event)
            app.Gap_strength = app.GapStrengthEditField.Value;
            
        end

        % Value changed function: InterconnectivityDropDown
        function InterconnectivityDropDownValueChanged(app, event)
            if app.InterconnectivityDropDown.Value == "Full Symmetry"
                app.inter_symmetry = "Hernan Symmetric Weighted";
            elseif app.InterconnectivityDropDown.Value == "LR Symmetry"
                app.inter_symmetry = "LR Symmetric Weighted";
            elseif app.InterconnectivityDropDown.Value == "Natrual"
                app.inter_symmetry = "Varshney Weighted";
            end
        end

        % Value changed function: IntraconnectivityDropDown
        function IntraconnectivityDropDownValueChanged(app, event)
            if app.IntraconnectivityDropDown.Value == "LR Symmetry"
                app.intra_symmetry = "LR Symmetric Weighted";
            elseif app.IntraconnectivityDropDown.Value == "Natrual"
                app.intra_symmetry = "Varshney Weighted";
            end
        end

        % Value changed function: RandomInitalStateButton
        function RandomInitalStateButtonValueChanged(app, event)
            if app.RandomInitalStateButton.Value == 0
                app.stdinitialVoltmVEditField.Value = 0;
                app.stdinitialVoltmVEditField.Enable = 'off';
                app.stdinitialVoltmVEditFieldLabel.Enable = 'off';
            elseif app.RandomInitalStateButton.Value
                app.stdinitialVoltmVEditField.Value = 0.5;
                app.stdinitialVoltmVEditField.Enable = 'on';
                app.stdinitialVoltmVEditFieldLabel.Enable = 'on';                
            end
        end

        % Value changed function: NoisyConnectomeButton
        function NoisyConnectomeButtonValueChanged(app, event)
            if app.NoisyConnectomeButton.Value == 0
                app.stdedgeweightsEditField.Value=0;
                app.stdedgeweightsEditField.Enable = 'off';
                app.stdedgeweightsEditFieldLabel.Enable = 'off';
            elseif app.NoisyConnectomeButton.Value == 1
                app.stdedgeweightsEditField.Enable = 'on';
                app.stdedgeweightsEditFieldLabel.Enable = 'on';
                app.stdedgeweightsEditField.Value=0.001;
            end
        end

        % Value changed function: SynapticVariableButton
        function SynapticVariableButtonValueChanged(app, event)
            if app.SynapticVariableButton.Value == 0
                app.meansynvarEditField.Value=0;
                app.meansynvarEditField.Enable = 'off';
                app.meansynvarEditFieldLabel.Enable = 'off';
                app.stdsynvarEditField.Enable = 'off';
                app.stdsynvarEditFieldLabel.Enable = 'off';
            elseif app.SynapticVariableButton.Value == 1
                app.meansynvarEditField.Enable = 'on';
                app.meansynvarEditFieldLabel.Enable = 'on';
                app.stdsynvarEditField.Enable = 'on';
                app.stdsynvarEditFieldLabel.Enable = 'on';
                app.meansynvarEditField.Value=0.09;
            end         
        end

        % Value changed function: SynchMeasureDropDown
        function SynchMeasureDropDownValueChanged(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ViewSwitchLabel = uilabel(app.UIFigure);
            app.ViewSwitchLabel.Text = 'View';
            app.ViewSwitch = uiswitch(app.UIFigure, 'slider');
            app.ViewSwitch.Items = {'Chem', 'Gap'};
            app.ViewSwitch.ValueChangedFcn = createCallbackFcn(app, @ViewSwitchValueChanged, true);
            app.ViewSwitch.Value = 'Chem';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.IntraconnectedButton = uibutton(app.UIFigure, 'state');
            app.IntraconnectedButton.ValueChangedFcn = createCallbackFcn(app, @IntraconnectedButtonValueChanged, true);
            app.IntraconnectedButton.Text = 'Intra-connected';
            app.WeightsSwitchLabel = uilabel(app.UIFigure);
            app.WeightsSwitchLabel.Text = 'Weights';
            app.WeightsSwitch = uiswitch(app.UIFigure, 'slider');
            app.WeightsSwitch.Items = {'Binary', 'Integer'};
            app.WeightsSwitch.ValueChangedFcn = createCallbackFcn(app, @WeightsSwitchValueChanged, true);
            app.WeightsSwitch.Value = 'Binary';
            app.InterconnectivityDropDownLabel = uilabel(app.UIFigure);
            app.InterconnectivityDropDownLabel.Text = 'Inter-connectivity';
            app.InterconnectivityDropDown = uidropdown(app.UIFigure);
            app.InterconnectivityDropDown.Items = {'Full Symmetry', 'LR Symmetry', 'Natrual'};
            app.InterconnectivityDropDown.ValueChangedFcn = createCallbackFcn(app, @InterconnectivityDropDownValueChanged, true);
            app.InterconnectivityDropDown.Value = 'Full Symmetry';
            app.IntraconnectivityDropDownLabel = uilabel(app.UIFigure);
            app.IntraconnectivityDropDownLabel.Enable = 'off';
            app.IntraconnectivityDropDownLabel.Text = 'Intra-connectivity';
            app.IntraconnectivityDropDown = uidropdown(app.UIFigure);
            app.IntraconnectivityDropDown.Items = {'LR Symmery', 'Natrual'};
            app.IntraconnectivityDropDown.ValueChangedFcn = createCallbackFcn(app, @IntraconnectivityDropDownValueChanged, true);
            app.IntraconnectivityDropDown.Enable = 'off';
            app.IntraconnectivityDropDown.Value = 'LR Symmery';
            app.GapStrengthEditFieldLabel = uilabel(app.UIFigure);
            app.GapStrengthEditFieldLabel.Text = 'Gap Strength';
            app.GapStrengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.GapStrengthEditField.Limits = [0 1];
            app.GapStrengthEditField.ValueChangedFcn = createCallbackFcn(app, @GapStrengthEditFieldValueChanged, true);
            app.ChemStrengthEditFieldLabel = uilabel(app.UIFigure);
            app.ChemStrengthEditFieldLabel.Text = 'Chem Strength';
            app.BackwardNeuronsDrivingFrequencyHzLabel = uilabel(app.UIFigure);
            app.BackwardNeuronsDrivingFrequencyHzLabel.Text = 'Backward Neurons - Driving Frequency [Hz]';
            app.DA01EditFieldLabel = uilabel(app.UIFigure);
            app.DA01EditFieldLabel.Text = 'DA01';
            app.DA01EditField = uieditfield(app.UIFigure, 'numeric');
            app.DA02EditFieldLabel = uilabel(app.UIFigure);
            app.DA02EditFieldLabel.Text = 'DA02';
            app.DA02EditField = uieditfield(app.UIFigure, 'numeric');
            app.DA03EditFieldLabel = uilabel(app.UIFigure);
            app.DA03EditFieldLabel.Text = 'DA03';
            app.DA03EditField = uieditfield(app.UIFigure, 'numeric');
            app.DA04EditFieldLabel = uilabel(app.UIFigure);
            app.DA04EditFieldLabel.Text = 'DA04';
            app.DA04EditField = uieditfield(app.UIFigure, 'numeric');
            app.DA05EditFieldLabel = uilabel(app.UIFigure);
            app.DA05EditFieldLabel.Text = 'DA05';
            app.DA05EditField = uieditfield(app.UIFigure, 'numeric');
            app.DA06EditFieldLabel = uilabel(app.UIFigure);
            app.DA06EditFieldLabel.Text = 'DA06';
            app.DA06EditField = uieditfield(app.UIFigure, 'numeric');
            app.DA07EditFieldLabel = uilabel(app.UIFigure);
            app.DA07EditFieldLabel.Text = 'DA07';
            app.DA07EditField = uieditfield(app.UIFigure, 'numeric');
            app.DA08EditFieldLabel = uilabel(app.UIFigure);
            app.DA08EditFieldLabel.Text = 'DA08';
            app.DA08EditField = uieditfield(app.UIFigure, 'numeric');
            app.DA09EditFieldLabel = uilabel(app.UIFigure);
            app.DA09EditFieldLabel.Text = 'DA09';
            app.DA09EditField = uieditfield(app.UIFigure, 'numeric');
            app.VA01EditFieldLabel = uilabel(app.UIFigure);
            app.VA01EditFieldLabel.Text = 'VA01';
            app.VA01EditField = uieditfield(app.UIFigure, 'numeric');
            app.VA02EditFieldLabel = uilabel(app.UIFigure);
            app.VA02EditFieldLabel.Text = 'VA02';
            app.VA02EditField = uieditfield(app.UIFigure, 'numeric');
            app.VA03EditFieldLabel = uilabel(app.UIFigure);
            app.VA03EditFieldLabel.Text = 'VA03';
            app.VA03EditField = uieditfield(app.UIFigure, 'numeric');
            app.VA04EditFieldLabel = uilabel(app.UIFigure);
            app.VA04EditFieldLabel.Text = 'VA04';
            app.VA04EditField = uieditfield(app.UIFigure, 'numeric');
            app.VA05EditFieldLabel = uilabel(app.UIFigure);
            app.VA05EditFieldLabel.Text = 'VA05';
            app.VA05EditField = uieditfield(app.UIFigure, 'numeric');
            app.VA06EditFieldLabel = uilabel(app.UIFigure);
            app.VA06EditFieldLabel.Text = 'VA06';
            app.VA06EditField = uieditfield(app.UIFigure, 'numeric');
            app.VA07EditFieldLabel = uilabel(app.UIFigure);
            app.VA07EditFieldLabel.Text = 'VA07';
            app.VA07EditField = uieditfield(app.UIFigure, 'numeric');
            app.VA08EditFieldLabel = uilabel(app.UIFigure);
            app.VA08EditFieldLabel.Text = 'VA08';
            app.VA08EditField = uieditfield(app.UIFigure, 'numeric');
            app.VA09EditFieldLabel = uilabel(app.UIFigure);
            app.VA09EditFieldLabel.Text = 'VA09';
            app.VA09EditField = uieditfield(app.UIFigure, 'numeric');
            app.VA10EditFieldLabel = uilabel(app.UIFigure);
            app.VA10EditFieldLabel.Text = 'VA10';
            app.VA10EditField = uieditfield(app.UIFigure, 'numeric');
            app.VA11EditFieldLabel = uilabel(app.UIFigure);
            app.VA11EditFieldLabel.Text = 'VA11';
            app.VA11EditField = uieditfield(app.UIFigure, 'numeric');
            app.VA12EditFieldLabel = uilabel(app.UIFigure);
            app.VA12EditFieldLabel.Text = 'VA12';
            app.VA12EditField = uieditfield(app.UIFigure, 'numeric');
            app.AVEREditFieldLabel = uilabel(app.UIFigure);
            app.AVEREditFieldLabel.Text = 'AVER';
            app.AVEREditField = uieditfield(app.UIFigure, 'numeric');
            app.AVELEditFieldLabel = uilabel(app.UIFigure);
            app.AVELEditFieldLabel.Text = 'AVEL';
            app.AVELEditField = uieditfield(app.UIFigure, 'numeric');
            app.AVDREditFieldLabel = uilabel(app.UIFigure);
            app.AVDREditFieldLabel.Text = 'AVDR';
            app.AVDREditField = uieditfield(app.UIFigure, 'numeric');
            app.AVDLEditFieldLabel = uilabel(app.UIFigure);
            app.AVDLEditFieldLabel.Text = 'AVDL';
            app.AVDLEditField = uieditfield(app.UIFigure, 'numeric');
            app.RIMREditFieldLabel = uilabel(app.UIFigure);
            app.RIMREditFieldLabel.Text = 'RIMR';
            app.RIMREditField = uieditfield(app.UIFigure, 'numeric');
            app.RIMLEditFieldLabel = uilabel(app.UIFigure);
            app.RIMLEditFieldLabel.Text = 'RIML';
            app.RIMLEditField = uieditfield(app.UIFigure, 'numeric');
            app.AIBREditFieldLabel = uilabel(app.UIFigure);
            app.AIBREditFieldLabel.Text = 'AIBR';
            app.AIBREditField = uieditfield(app.UIFigure, 'numeric');
            app.AIBLEditFieldLabel = uilabel(app.UIFigure);
            app.AIBLEditFieldLabel.Text = 'AIBL';
            app.AIBLEditField = uieditfield(app.UIFigure, 'numeric');
            app.AVAREditFieldLabel = uilabel(app.UIFigure);
            app.AVAREditFieldLabel.Text = 'AVAR';
            app.AVAREditField = uieditfield(app.UIFigure, 'numeric');
            app.AVAREditField.Value = 1;
            app.AVALEditFieldLabel = uilabel(app.UIFigure);
            app.AVALEditFieldLabel.Text = 'AVAL';
            app.AVALEditField = uieditfield(app.UIFigure, 'numeric');
            app.AVALEditField.Value = 1;
            app.ForwardNeuronsDrivingFrequencyHzLabel = uilabel(app.UIFigure);
            app.ForwardNeuronsDrivingFrequencyHzLabel.Text = 'Forward Neurons - Driving Frequency [Hz]';
            app.DB01EditFieldLabel = uilabel(app.UIFigure);
            app.DB01EditFieldLabel.Text = 'DB01';
            app.DB01EditField = uieditfield(app.UIFigure, 'numeric');
            app.DB02EditFieldLabel = uilabel(app.UIFigure);
            app.DB02EditFieldLabel.Text = 'DB02';
            app.DB02EditField = uieditfield(app.UIFigure, 'numeric');
            app.DB03EditFieldLabel = uilabel(app.UIFigure);
            app.DB03EditFieldLabel.Text = 'DB03';
            app.DB03EditField = uieditfield(app.UIFigure, 'numeric');
            app.DB04EditFieldLabel = uilabel(app.UIFigure);
            app.DB04EditFieldLabel.Text = 'DB04';
            app.DB04EditField = uieditfield(app.UIFigure, 'numeric');
            app.DB05EditFieldLabel = uilabel(app.UIFigure);
            app.DB05EditFieldLabel.Text = 'DB05';
            app.DB05EditField = uieditfield(app.UIFigure, 'numeric');
            app.DB06EditFieldLabel = uilabel(app.UIFigure);
            app.DB06EditFieldLabel.Text = 'DB06';
            app.DB06EditField = uieditfield(app.UIFigure, 'numeric');
            app.DB07EditFieldLabel = uilabel(app.UIFigure);
            app.DB07EditFieldLabel.Text = 'DB07';
            app.DB07EditField = uieditfield(app.UIFigure, 'numeric');
            app.VB01EditFieldLabel = uilabel(app.UIFigure);
            app.VB01EditFieldLabel.Text = 'VB01';
            app.VB01EditField = uieditfield(app.UIFigure, 'numeric');
            app.VB02EditFieldLabel = uilabel(app.UIFigure);
            app.VB02EditFieldLabel.Text = 'VB02';
            app.VB02EditField = uieditfield(app.UIFigure, 'numeric');
            app.VB03EditFieldLabel = uilabel(app.UIFigure);
            app.VB03EditFieldLabel.Text = 'VB03';
            app.VB03EditField = uieditfield(app.UIFigure, 'numeric');
            app.VB04EditFieldLabel = uilabel(app.UIFigure);
            app.VB04EditFieldLabel.Text = 'VB04';
            app.VB04EditField = uieditfield(app.UIFigure, 'numeric');
            app.VB05EditFieldLabel = uilabel(app.UIFigure);
            app.VB05EditFieldLabel.Text = 'VB05';
            app.VB05EditField = uieditfield(app.UIFigure, 'numeric');
            app.VB06EditFieldLabel = uilabel(app.UIFigure);
            app.VB06EditFieldLabel.Text = 'VB06';
            app.VB06EditField = uieditfield(app.UIFigure, 'numeric');
            app.VB07EditFieldLabel = uilabel(app.UIFigure);
            app.VB07EditFieldLabel.Text = 'VB07';
            app.VB07EditField = uieditfield(app.UIFigure, 'numeric');
            app.VB08EditFieldLabel = uilabel(app.UIFigure);
            app.VB08EditFieldLabel.Text = 'VB08';
            app.VB08EditField = uieditfield(app.UIFigure, 'numeric');
            app.VB09EditFieldLabel = uilabel(app.UIFigure);
            app.VB09EditFieldLabel.Text = 'VB09';
            app.VB09EditField = uieditfield(app.UIFigure, 'numeric');
            app.VB10EditFieldLabel = uilabel(app.UIFigure);
            app.VB10EditFieldLabel.Text = 'VB10';
            app.VB10EditField = uieditfield(app.UIFigure, 'numeric');
            app.VB11EditFieldLabel = uilabel(app.UIFigure);
            app.VB11EditFieldLabel.Text = 'VB11';
            app.VB11EditField = uieditfield(app.UIFigure, 'numeric');
            app.AVBREditFieldLabel = uilabel(app.UIFigure);
            app.AVBREditFieldLabel.Text = 'AVBR';
            app.AVBREditField = uieditfield(app.UIFigure, 'numeric');
            app.AVBLEditFieldLabel = uilabel(app.UIFigure);
            app.AVBLEditFieldLabel.Text = 'AVBL';
            app.AVBLEditField = uieditfield(app.UIFigure, 'numeric');
            app.PVCREditFieldLabel = uilabel(app.UIFigure);
            app.PVCREditFieldLabel.Text = 'PVCR';
            app.PVCREditField = uieditfield(app.UIFigure, 'numeric');
            app.PVCREditField.Value = 3;
            app.PVCLEditFieldLabel = uilabel(app.UIFigure);
            app.PVCLEditFieldLabel.Text = 'PVCL';
            app.PVCLEditField = uieditfield(app.UIFigure, 'numeric');
            app.PVCLEditField.Value = 3;
            app.RIBREditFieldLabel = uilabel(app.UIFigure);
            app.RIBREditFieldLabel.Text = 'RIBR';
            app.RIBREditField = uieditfield(app.UIFigure, 'numeric');
            app.RIBLEditFieldLabel = uilabel(app.UIFigure);
            app.RIBLEditFieldLabel.Text = 'RIBL';
            app.RIBLEditField = uieditfield(app.UIFigure, 'numeric');
            app.DrivingvoltageLabel = uilabel(app.UIFigure);
            app.DrivingvoltageLabel.Text = 'Oscill Currrent pA';
            app.OscillCurrrentpAEditField = uieditfield(app.UIFigure, 'numeric');
            app.OscillCurrrentpAEditField.Limits = [-5 5];
            app.OscillCurrrentpAEditField.Value = 0.5;
            app.ByBryantEAvilaLabel = uilabel(app.UIFigure);
            app.ByBryantEAvilaLabel.Text = 'By: Bryant E. Avila';
            app.ChemStrengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChemStrengthEditField.Limits = [0 1];
            app.ChemStrengthEditField.ValueChangedFcn = createCallbackFcn(app, @ChemStrengthEditFieldValueChanged, true);
            app.ChemStrengthEditField.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'City_College_of_New_York_seal.svg.png';
            app.SynapticVariableButton = uibutton(app.UIFigure, 'state');
            app.SynapticVariableButton.ValueChangedFcn = createCallbackFcn(app, @SynapticVariableButtonValueChanged, true);
            app.SynapticVariableButton.Text = 'Synaptic Variable';
            app.RandomInitalStateButton = uibutton(app.UIFigure, 'state');
            app.RandomInitalStateButton.ValueChangedFcn = createCallbackFcn(app, @RandomInitalStateButtonValueChanged, true);
            app.RandomInitalStateButton.Text = 'Random Inital State';
            app.DrivingCurrentpALabel = uilabel(app.UIFigure);
            app.DrivingCurrentpALabel.Text = 'Driving Current pA';
            app.DrivingCurrentpAEditField = uieditfield(app.UIFigure, 'numeric');
            app.DrivingCurrentpAEditField.Value = 1;
            app.UniversalthresholdButton = uibutton(app.UIFigure, 'state');
            app.UniversalthresholdButton.Text = 'Universal threshold';
            app.meaninitialVoltmVEditFieldLabel = uilabel(app.UIFigure);
            app.meaninitialVoltmVEditFieldLabel.Text = 'mean initial Volt (mV)';
            app.meaninitialVoltmVEditField = uieditfield(app.UIFigure, 'numeric');
            app.meaninitialVoltmVEditField.Value = -35;
            app.stdinitialVoltmVEditFieldLabel = uilabel(app.UIFigure);
            app.stdinitialVoltmVEditFieldLabel.Enable = 'off';
            app.stdinitialVoltmVEditFieldLabel.Text = 'std initial Volt (mV)';
            app.stdinitialVoltmVEditField = uieditfield(app.UIFigure, 'numeric');
            app.stdinitialVoltmVEditField.Limits = [0 Inf];
            app.stdinitialVoltmVEditField.Enable = 'off';
            app.NoisyConnectomeButton = uibutton(app.UIFigure, 'state');
            app.NoisyConnectomeButton.ValueChangedFcn = createCallbackFcn(app, @NoisyConnectomeButtonValueChanged, true);
            app.NoisyConnectomeButton.Text = 'Noisy Connectome';
            app.ChemButton = uibutton(app.UIFigure, 'state');
            app.ChemButton.Text = 'Chem';
            app.stdedgeweightsEditFieldLabel = uilabel(app.UIFigure);
            app.stdedgeweightsEditFieldLabel.Enable = 'off';
            app.stdedgeweightsEditFieldLabel.Text = 'std edge weights';
            app.stdedgeweightsEditField = uieditfield(app.UIFigure, 'numeric');
            app.stdedgeweightsEditField.Limits = [0 100];
            app.stdedgeweightsEditField.Enable = 'off';
            app.NormalizeindegreesLabel = uilabel(app.UIFigure);
            app.NormalizeindegreesLabel.Text = ' Normalize in-degrees';
            app.GapButton = uibutton(app.UIFigure, 'state');
            app.GapButton.Text = 'Gap';
            app.RuntimesecEditFieldLabel = uilabel(app.UIFigure);
            app.RuntimesecEditFieldLabel.Text = 'Run-time (sec.)';
            app.RuntimesecEditField = uieditfield(app.UIFigure, 'numeric');
            app.RuntimesecEditField.Limits = [0 100];
            app.RuntimesecEditField.Value = 5;
            app.meansynvarEditFieldLabel = uilabel(app.UIFigure);
            app.meansynvarEditFieldLabel.Enable = 'off';
            app.meansynvarEditFieldLabel.Text = 'mean syn. var.';
            app.meansynvarEditField = uieditfield(app.UIFigure, 'numeric');
            app.meansynvarEditField.Enable = 'off';
            app.meansynvarEditField.Value = 0.09;
            app.stdsynvarEditFieldLabel = uilabel(app.UIFigure);
            app.stdsynvarEditFieldLabel.Enable = 'off';
            app.stdsynvarEditFieldLabel.Text = 'std syn. var.';
            app.stdsynvarEditField = uieditfield(app.UIFigure, 'numeric');
            app.stdsynvarEditField.Limits = [0 Inf];
            app.stdsynvarEditField.Enable = 'off';
            app.NoiseAmppAEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseAmppAEditFieldLabel.Text = 'Noise Amp. pA';
            app.NoiseAmppAEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoiseAmppAEditField.Limits = [0 100];
            app.SynchMeasureDropDownLabel = uilabel(app.UIFigure);
            app.SynchMeasureDropDownLabel.Text = 'Synch. Measure';
            app.SynchMeasureDropDown = uidropdown(app.UIFigure);
            app.SynchMeasureDropDown.Items = {'LoS', 'PLV', 'mod(π)_PLV', 'mod(π)_Phase_Diff', 'Std_Diff'};
            app.SynchMeasureDropDown.ValueChangedFcn = createCallbackFcn(app, @SynchMeasureDropDownValueChanged, true);
            app.SynchMeasureDropDown.Value = 'LoS';
            app.RndSeedEditFieldLabel = uilabel(app.UIFigure);
            app.RndSeedEditFieldLabel.Text = 'Rnd Seed';
            app.RndSeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.RndSeedEditField.Limits = [0 Inf];
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CelegansLocomotion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end